classdef basicTraining < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OptionsPanel                matlab.ui.container.Panel
        MovieSizedegEditFieldLabel  matlab.ui.control.Label
        MovieSizedegEditField       matlab.ui.control.NumericEditField
        RewardatstartCheckBox       matlab.ui.control.CheckBox
        RewardduringCheckBox        matlab.ui.control.CheckBox
        RewardatendCheckBox         matlab.ui.control.CheckBox
        PlaytimesEditFieldLabel     matlab.ui.control.Label
        PlaytimesEditField          matlab.ui.control.NumericEditField
        HTML                        matlab.ui.control.HTML
        SPButton                    matlab.ui.control.Button
        InfoEditFieldLabel          matlab.ui.control.Label
        InfoEditField               matlab.ui.control.EditField
        SubjectEditFieldLabel       matlab.ui.control.Label
        SubjectEditField            matlab.ui.control.EditField
        SavePathEditFieldLabel      matlab.ui.control.Label
        SavePathEditField           matlab.ui.control.EditField
        GOButton                    matlab.ui.control.Button
        DistanceEditFieldLabel      matlab.ui.control.Label
        DistanceEditField           matlab.ui.control.NumericEditField
        PixelsPerCmEditFieldLabel   matlab.ui.control.Label
        PixelsPerCmEditField        matlab.ui.control.NumericEditField
        ResultsPanel                matlab.ui.container.Panel
        PlotAxis1                   matlab.ui.control.UIAxes
        PlotAxis2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ana % UI state passed to matlab
    end
    
    methods (Access = private)
        
        function results = parseSettings(app)
           app.ana = [];
           app.ana.uuid = num2str(dec2hex(floor((now - floor(now))*1e10)));
           app.ana.subject = app.SubjectEditField.Value;
           app.ana.comments = app.InfoEditField.Value;
           app.ana.ResultDir = app.SavePathEditField.Value;
           app.ana.pixelsPerCm = app.PixelsPerCmEditField.Value;
           app.ana.distance = app.DistanceEditField.Value;
           app.ana.size = app.MovieSizedegEditField.Value;
           app.ana.playTimes = app.PlaytimesEditField.Value;
           app.ana.rewardStart = logical(app.RewardatstartCheckBox.Value);
           app.ana.rewardDuring = logical(app.RewardduringCheckBox.Value);
           app.ana.rewardEnd = logical(app.RewardatendCheckBox.Value);
           
           app.ana.plotAxis1 = app.PlotAxis1;
           app.ana.plotAxis2 = app.PlotAxis2;
            
        end
        
        function p = setPath(app)
            s=screenManager();
            app.SavePathEditField.Value=s.paths.savedData;
            p = app.SavePathEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SPButton
        function SPButtonPushed(app, event)
           p=app.setPath();
           cd(p);
           app.SavePathEditField.Value = uigetdir;
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           event.Source.BusyAction = 'cancel';
           event.Source.Interruptible = 'off';
           app.parseSettings();
           fprintf('\n=========GO BUTTON PUSHED============\n')
           runBasicTraining(app.ana)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.MovieSizedegEditFieldLabel = uilabel(app.OptionsPanel);
            app.MovieSizedegEditFieldLabel.Text = 'Movie Size (deg)';
            app.MovieSizedegEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.RewardatstartCheckBox = uicheckbox(app.OptionsPanel);
            app.RewardatstartCheckBox.Text = 'Reward at start?';
            app.RewardduringCheckBox = uicheckbox(app.OptionsPanel);
            app.RewardduringCheckBox.Text = 'Reward during?';
            app.RewardatendCheckBox = uicheckbox(app.OptionsPanel);
            app.RewardatendCheckBox.Text = 'Reward at end?';
            app.PlaytimesEditFieldLabel = uilabel(app.OptionsPanel);
            app.PlaytimesEditFieldLabel.Text = 'Play time (s)';
            app.PlaytimesEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.PlaytimesEditField.Value = 2;
            app.HTML = uihtml(app.OptionsPanel);
            app.HTML.HTMLSource = '<div style="text-align:justify;font-size:small;font-family:avenir,sans-serif;"><p >You can use automatic rewards or manual ones. Press:<br/><br/><b>1</b> Give reward.<br/><b>2</b> Give positive feedback + reward. <br/><b>3</b> Give <i>negative</i> feedback.<br/><b>p</b> Pause.<br/><b>q</b> Quit.</p></div>';
            app.SPButton = uibutton(app.OptionsPanel, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
            app.SPButton.Icon = 'go-to-file.png';
            app.SPButton.Enable = 'off';
            app.SPButton.Text = '';
            app.InfoEditFieldLabel = uilabel(app.OptionsPanel);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.OptionsPanel, 'text');
            app.SubjectEditFieldLabel = uilabel(app.OptionsPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.OptionsPanel, 'text');
            app.SubjectEditField.Value = 'Joanna Doe';
            app.SavePathEditFieldLabel = uilabel(app.OptionsPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.OptionsPanel, 'text');
            app.SavePathEditField.Value = '/home/aristotle/Desktop/IsoLum/';
            app.GOButton = uibutton(app.OptionsPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Icon = 'start.png';
            app.GOButton.Text = 'GO!';
            app.DistanceEditFieldLabel = uilabel(app.OptionsPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.DistanceEditField.Value = 60;
            app.PixelsPerCmEditFieldLabel = uilabel(app.OptionsPanel);
            app.PixelsPerCmEditFieldLabel.Text = 'PixelsPerCm';
            app.PixelsPerCmEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.PixelsPerCmEditField.Value = 32;
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.PlotAxis1 = uiaxes(app.ResultsPanel);
            title(app.PlotAxis1, 'Rewards')
            xlabel(app.PlotAxis1, '')
            ylabel(app.PlotAxis1, '#')
            app.PlotAxis2 = uiaxes(app.ResultsPanel);
            title(app.PlotAxis2, 'Responses')
            xlabel(app.PlotAxis2, '')
            ylabel(app.PlotAxis2, '#')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basicTraining

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end