classdef binocularMRI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		BinocularMRIUIFigure            matlab.ui.Figure
		BinocularMRIStimuluatorV105Panel  matlab.ui.container.Panel
		TestButton                      matlab.ui.control.Button
		SubjectNameEditField            matlab.ui.control.EditField
		SubjectNameEditFieldLabel       matlab.ui.control.Label
		TemporalFrequencyEditField      matlab.ui.control.NumericEditField
		TemporalFrequencyEditFieldLabel  matlab.ui.control.Label
		debugCheckBox                   matlab.ui.control.CheckBox
		RepeatsEditField                matlab.ui.control.NumericEditField
		RepeatsEditFieldLabel           matlab.ui.control.Label
		StimInterstimTimesEditField     matlab.ui.control.EditField
		StimInterstimTimesEditFieldLabel  matlab.ui.control.Label
		SFModCheckerboardEditField      matlab.ui.control.NumericEditField
		SFModCheckerboardEditFieldLabel  matlab.ui.control.Label
		AngleModCheckerboardEditField   matlab.ui.control.NumericEditField
		AngleModCheckerboardEditFieldLabel  matlab.ui.control.Label
		FlashRateEditField              matlab.ui.control.NumericEditField
		FlashRateEditFieldLabel         matlab.ui.control.Label
		SpatialFrequencyEditField       matlab.ui.control.NumericEditField
		SpatialFrequencyEditFieldLabel  matlab.ui.control.Label
		StimulusListBox                 matlab.ui.control.ListBox
		StimulusListBoxLabel            matlab.ui.control.Label
		RightRGBEditField               matlab.ui.control.EditField
		RightRGBEditFieldLabel          matlab.ui.control.Label
		LeftRGBEditField                matlab.ui.control.EditField
		LeftRGBEditFieldLabel           matlab.ui.control.Label
		BackgroundEditField             matlab.ui.control.EditField
		BackgroundEditFieldLabel        matlab.ui.control.Label
		StereoModeEditField             matlab.ui.control.NumericEditField
		StereoModeEditFieldLabel        matlab.ui.control.Label
		QuitButton                      matlab.ui.control.Button
		RunButton                       matlab.ui.control.Button
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Button pushed function: RunButton, TestButton
		function RunButtonPushed(app, event)

			if strcmpi(event.Source.Text,'Test')
				in.test = true;
			else
				in.test = false;
			end
			
			in.name = app.SubjectNameEditField.Value;
			in.stereoMode = app.StereoModeEditField.Value;
			in.bg = str2num(app.BackgroundEditField.Value);
			in.left = str2num(app.LeftRGBEditField.Value);
			in.right = str2num(app.RightRGBEditField.Value);
			in.type = app.StimulusListBox.Value;
			in.sf = app.SpatialFrequencyEditField.Value;
			in.tf = app.TemporalFrequencyEditField.Value;
			in.flash = app.FlashRateEditField.Value;
			in.anglemod = app.AngleModCheckerboardEditField.Value;
			in.sfmod = app.SFModCheckerboardEditField.Value;
			in.repeats =  app.RepeatsEditField.Value;
			in.times = str2num(app.StimInterstimTimesEditField.Value);
			in.debug = app.debugCheckBox.Value;

			binocularStimulation(in);
		end

		% Button pushed function: QuitButton
		function QuitButtonPushed(app, event)
			delete(app);
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.BinocularMRIUIFigure = uifigure('Visible', 'off');
			app.BinocularMRIUIFigure.Name = 'Binocular MRI';
			app.BinocularMRIStimuluatorV105Panel = uipanel(app.BinocularMRIUIFigure);
			app.BinocularMRIStimuluatorV105Panel.Title = 'Binocular MRI Stimuluator V1.05';
			app.RunButton = uibutton(app.BinocularMRIStimuluatorV105Panel, 'push');
			app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
			app.RunButton.Text = 'Run!';
			app.QuitButton = uibutton(app.BinocularMRIStimuluatorV105Panel, 'push');
			app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
			app.QuitButton.Text = 'Quit';
			app.StereoModeEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.StereoModeEditFieldLabel.Text = 'Stereo Mode';
			app.StereoModeEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'numeric');
			app.StereoModeEditField.Value = 8;
			app.BackgroundEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.BackgroundEditFieldLabel.Text = 'Background';
			app.BackgroundEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'text');
			app.BackgroundEditField.Value = '0  0  0';
			app.LeftRGBEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.LeftRGBEditFieldLabel.Text = 'Left RGB';
			app.LeftRGBEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'text');
			app.LeftRGBEditField.Value = '0.9  0  0';
			app.RightRGBEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.RightRGBEditFieldLabel.Text = 'Right RGB';
			app.RightRGBEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'text');
			app.RightRGBEditField.Value = '0  0.3  1';
			app.StimulusListBoxLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.StimulusListBoxLabel.Text = 'Stimulus';
			app.StimulusListBox = uilistbox(app.BinocularMRIStimuluatorV105Panel);
			app.StimulusListBox.Items = {'Checkerboard', 'Spiral', 'Radial', 'Circular'};
			app.StimulusListBox.Value = 'Checkerboard';
			app.SpatialFrequencyEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.SpatialFrequencyEditFieldLabel.Text = 'Spatial Frequency';
			app.SpatialFrequencyEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'numeric');
			app.SpatialFrequencyEditField.Value = 0.5;
			app.FlashRateEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.FlashRateEditFieldLabel.Text = 'Flash Rate';
			app.FlashRateEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'numeric');
			app.AngleModCheckerboardEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.AngleModCheckerboardEditFieldLabel.Text = 'Angle Mod Checkerboard';
			app.AngleModCheckerboardEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'numeric');
			app.AngleModCheckerboardEditField.Value = 0.1;
			app.SFModCheckerboardEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.SFModCheckerboardEditFieldLabel.Text = 'SF Mod Checkerboard';
			app.SFModCheckerboardEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'numeric');
			app.SFModCheckerboardEditField.Value = 0.3;
			app.StimInterstimTimesEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.StimInterstimTimesEditFieldLabel.Text = 'Stim / Inter-stim Time (s)';
			app.StimInterstimTimesEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'text');
			app.StimInterstimTimesEditField.Value = '16 16';
			app.RepeatsEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.RepeatsEditFieldLabel.Text = 'Repeats';
			app.RepeatsEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'numeric');
			app.RepeatsEditField.Value = 5;
			app.debugCheckBox = uicheckbox(app.BinocularMRIStimuluatorV105Panel);
			app.debugCheckBox.Text = 'debug';
			app.TemporalFrequencyEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.TemporalFrequencyEditFieldLabel.Text = 'Temporal Frequency';
			app.TemporalFrequencyEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'numeric');
			app.SubjectNameEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV105Panel);
			app.SubjectNameEditFieldLabel.Text = 'Subject Name';
			app.SubjectNameEditField = uieditfield(app.BinocularMRIStimuluatorV105Panel, 'text');
			app.SubjectNameEditField.Value = 'Test';
			app.TestButton = uibutton(app.BinocularMRIStimuluatorV105Panel, 'push');
			app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
			app.TestButton.Text = 'Test';
			% Show the figure after all components are created
			app.BinocularMRIUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = binocularMRI

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.BinocularMRIUIFigure)
			else

				% Focus the running singleton app
				figure(runningApp.BinocularMRIUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.BinocularMRIUIFigure)
		end
	end
end